classdef Joshua < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UITable          matlab.ui.control.Table
        GraphButton      matlab.ui.control.Button
        LoadDataButton   matlab.ui.control.Button
        CVSSscoreButton  matlab.ui.control.Button
        OverviewButton   matlab.ui.control.Button
        TextArea         matlab.ui.control.TextArea
        HomeButton       matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        kev %This property will hold the data allowing for easy access within the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function JoshuasapppresstogobackButtonPushed(app, event)
            app.delete

        end

        % Button pushed function: HomeButton
        function Home(app, event)
            Homepage
            app.delete
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            
            app.TextArea.Value = " Supervisory Control " + ...
                "and Data Acquisition (SCADA) systems are used for controlling, " + ...
                "monitoring, and analyzing industrial devices and processes. " + ...
                "The system consists of both software and hardware components " + ...
                "and enables remote and on-site gathering " + ...
                "of data from the industrial equipment. They are integral to the operation of dams." + ...
                "SCADA systems are a highly valuable target to attackers. Gaining access " + ...
                "to the SCADA system gives attackers full control of the dam/industrial asset " + ...
                "SCADA systems also handle data from various sensors in the system " + ...
                "that are used to keep track of processes, attackers may be able to " + ...
                "get this information in a hack. These are the main avenues for exploiting SCADA systems." + ...
                "This app will take user input to assess the exploitability of a system" + ...
                " and simulate the severity of an attack"
   

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Loading in data      
            app.kev = readtable("Book1.xlsx");
            dates = table2array(app.kev(:,1))
            severity = table2array(app.kev(:,2))

            %fill the table 
            app.UITable.Data = app.kev

            %This code generates the plot
            plot(app.UIAxes, dates,severity,'o')
            
            title(app.UIAxes, "\color{white}SCADA Vulnerabilities")
            xlabel(app.UIAxes, "Days")
            ylabel(app.UIAxes, "Severity")
            
        end

        % Button pushed function: CVSSscoreButton
        function CVSSscoreButtonPushed(app, event)
 

            assessment

        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Home, true);
            app.HomeButton.Text = 'Home';
            app.TextArea = uitextarea(app.UIFigure);
            app.OverviewButton = uibutton(app.UIFigure, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Text = 'Overview';
            app.CVSSscoreButton = uibutton(app.UIFigure, 'push');
            app.CVSSscoreButton.ButtonPushedFcn = createCallbackFcn(app, @CVSSscoreButtonPushed, true);
            app.CVSSscoreButton.Text = 'CVSS score';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Days'; 'Severity'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Joshua

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end