classdef assessment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        DefinitionsButton               matlab.ui.control.Button
        cvssresult                      matlab.ui.control.TextArea
        CVSSScoreLabel                  matlab.ui.control.Label
        cvss                            matlab.ui.container.CheckBoxTree
        ImpactNode                      matlab.ui.container.TreeNode
        ConfidentialityNode             matlab.ui.container.TreeNode
        EncryptionNode                  matlab.ui.container.TreeNode
        IntegrityNode                   matlab.ui.container.TreeNode
        HashingNode                     matlab.ui.container.TreeNode
        AvailabilityNode                matlab.ui.container.TreeNode
        BackupNode                      matlab.ui.container.TreeNode
        ExploitabilityNode              matlab.ui.container.TreeNode
        AuthenticationNode              matlab.ui.container.TreeNode
        MFANode                         matlab.ui.container.TreeNode
        AcessControlsNode               matlab.ui.container.TreeNode
        DualControlNode                 matlab.ui.container.TreeNode
        RBACNode                        matlab.ui.container.TreeNode
        AccessVectorNode                matlab.ui.container.TreeNode
        AirgappedNode                   matlab.ui.container.TreeNode
        BackButton                      matlab.ui.control.Button
        CVSSScoreButton                 matlab.ui.control.Button
        NetworkSecurityTextArea         matlab.ui.control.TextArea
        NetworkSecurityTextAreaLabel    matlab.ui.control.Label
        NumberofTurbinesEditField       matlab.ui.control.NumericEditField
        NumberofTurbinesEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        measures
        over 


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofTurbinesEditField
        function NumberofTurbinesEditFieldValueChanged(app, event)
            turbines = app.NumberofTurbinesEditField.Value;
            attSurf = turbines*2;
            app.NetworkSecurityTextArea.Value = sprintf("Your system has %.0f possible infiltration points ", attSurf);
            
        end

        % Button pushed function: CVSSScoreButton
        function CVSSScoreButtonPushed(app, event)
selectedNodes = app.cvss.SelectedNodes;

            AccessVector = .646;
            

            % Impact Calculations

            %Confidentiality Score
            if(selectedNodes == "Encryption")
                ConfImpact = 0;
            else
                ConfImpact = .66;
            end

            %Integrity Score
            if(selectedNodes == "Hashing")
                IntegImpact = 0;
            else
                IntegImpact = .66;
            end

            % Availability Score
            if(selectedNodes == "Backup")
                AvailImpact = 0;
            else
                AvailImpact = .66;
            end

            Impact = 10.41 * (1 - (1 - ConfImpact) * (1 - IntegImpact) * (1 - AvailImpact));


            %Exploitability Calculations  

            %Authentication Score
            if(selectedNodes == "MFA")
                Authentication = .45;
            else
                Authentication = .56;
            end

            %Access Complexity Score
            if(selectedNodes == "Dual Control" || selectedNodes == "RBAC")
                AccessComplexity = .61;
            elseif (selectedNodes == "Dual Control" && selectedNodes == "RBAC")
                AccessComplexity = .35;
            else 
                AccessComplexity = .71;
            end

            %Access Vector Score
            if(selectedNodes == "Airgapped")
                AccessComplexity = .395;
            else
                AccessComplexity = 1;
            end

            Exploitability = 20 * AccessComplexity * Authentication * AccessVector;


            %fimpact calculations
            if Impact == 0
                fImpact = 0;
            else
                fImpact =1.176;
            end
            

            %put it all together
              BaseScore = (.6*Impact +.4*Exploitability-1.5)*fImpact;
            app.cvssresult.Value = sprintf( "%.1f", BaseScore); 


        end

        % Callback function
        function TreeSelectionChanged(app, event)
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Joshua
            app.delete
        end

        % Callback function: cvss
        function cvssCheckedNodesChanged(app, event)
            checkedNodes = app.cvss.CheckedNodes;
            
            
        end

        % Selection changed function: cvss
        function cvssSelectionChanged(app, event)
         selectedNode = app.cvss.SelectedNodes;
           
            
        end

        % Button pushed function: DefinitionsButton
        function DefinitionsButtonPushed(app, event)
            %display definitions
            app.TextArea.Value = "Role-Based Access Control (RBAC): " + ...
    "A security model that restricts system access to authorized users " + ...
    "based on their roles within an organization. Roles are assigned " + ...
    "specific permissions, and users are granted access to resources " + ...
    "based on their assigned role rather than individual permissions. " + ...
    "Simplifies access management and reduces administrative overhead.\n" + ...    
    "Dual Control (Two-Person Control): " + ...
    "A security mechanism where two authorized individuals are required " + ...
    "to perform or approve an action. This ensures no single individual " + ...
    "has full control over sensitive tasks, commonly used in cryptographic " + ...
    "systems or high-security operations." +  ...
    "Encryption: " + ...
    "The process of converting plaintext data into an unreadable form " + ...
    "(ciphertext) using an algorithm and a key. Only those with the decryption " + ...
    "key can restore the original data, ensuring confidentiality in transit or at rest." +  ...
    "Hashing: " + ...
    "A one-way cryptographic function that converts data into a fixed-size string, " + ...
    "used to verify data integrity. Hashes are irreversible and unique for every unique " + ...
    "input. Commonly used in password storage and digital signatures." +  ...
    "Airgap: " + ...
    "A security measure that isolates a computer or network from other networks, " + ...
    "preventing any communication between them. This physical separation is used " + ...
    "to protect highly sensitive data or systems." + ...
    "Firewall: " + ...
    "A network security device or software that controls incoming and outgoing traffic " + ...
    "based on predefined security rules. Firewalls act as barriers between trusted " + ...
    "internal networks and untrusted external ones, preventing unauthorized access." +...
    "Multi-Factor Authentication (MFA): " + ...
    "A security process requiring two or more independent forms of authentication " + ...
    "to verify a user's identity. Common factors include something you know, " + ...
    "something you have, and something you are, significantly increasing security." + ...
    "Backup: " + ...
    "The process of creating a copy of data that can be restored in case of " + ...
    "data loss or corruption. Backups are crucial for disaster recovery and " + ...
    "are often stored offsite or in the cloud.";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofTurbinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTurbinesEditFieldLabel.Text = 'Number of Turbines';
            app.NumberofTurbinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTurbinesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofTurbinesEditFieldValueChanged, true);
            app.NetworkSecurityTextAreaLabel = uilabel(app.UIFigure);
            app.NetworkSecurityTextAreaLabel.Text = 'Network Security ';
            app.NetworkSecurityTextArea = uitextarea(app.UIFigure);
            app.CVSSScoreButton = uibutton(app.UIFigure, 'push');
            app.CVSSScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CVSSScoreButtonPushed, true);
            app.CVSSScoreButton.Text = 'CVSS Score';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.cvss = uitree(app.UIFigure, 'checkbox');
            app.cvss.SelectionChangedFcn = createCallbackFcn(app, @cvssSelectionChanged, true);
            app.cvss.Editable = 'on';
            app.ImpactNode = uitreenode(app.cvss);
            app.ImpactNode.Text = 'Impact';
            app.ConfidentialityNode = uitreenode(app.ImpactNode);
            app.ConfidentialityNode.Text = 'Confidentiality';
            app.EncryptionNode = uitreenode(app.ConfidentialityNode);
            app.EncryptionNode.Text = 'Encryption';
            app.IntegrityNode = uitreenode(app.ImpactNode);
            app.IntegrityNode.Text = 'Integrity';
            app.HashingNode = uitreenode(app.IntegrityNode);
            app.HashingNode.Text = 'Hashing';
            app.AvailabilityNode = uitreenode(app.ImpactNode);
            app.AvailabilityNode.Text = 'Availability';
            app.BackupNode = uitreenode(app.AvailabilityNode);
            app.BackupNode.Text = 'Backup';
            app.ExploitabilityNode = uitreenode(app.cvss);
            app.ExploitabilityNode.Text = 'Exploitability';
            app.AuthenticationNode = uitreenode(app.ExploitabilityNode);
            app.AuthenticationNode.Text = 'Authentication';
            app.MFANode = uitreenode(app.AuthenticationNode);
            app.MFANode.Text = 'MFA';
            app.AcessControlsNode = uitreenode(app.ExploitabilityNode);
            app.AcessControlsNode.Text = 'Acess Controls';
            app.DualControlNode = uitreenode(app.AcessControlsNode);
            app.DualControlNode.Text = 'Dual Control';
            app.RBACNode = uitreenode(app.AcessControlsNode);
            app.RBACNode.Text = 'RBAC';
            app.AccessVectorNode = uitreenode(app.cvss);
            app.AccessVectorNode.Text = 'Access Vector';
            app.AirgappedNode = uitreenode(app.AccessVectorNode);
            app.AirgappedNode.Text = 'Airgapped';
            % Assign Checked Nodes
            app.cvss.CheckedNodesChangedFcn = createCallbackFcn(app, @cvssCheckedNodesChanged, true);
            app.CVSSScoreLabel = uilabel(app.UIFigure);
            app.CVSSScoreLabel.Text = 'CVSS Score';
            app.cvssresult = uitextarea(app.UIFigure);
            app.DefinitionsButton = uibutton(app.UIFigure, 'push');
            app.DefinitionsButton.ButtonPushedFcn = createCallbackFcn(app, @DefinitionsButtonPushed, true);
            app.DefinitionsButton.Text = 'Definitions';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assessment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end