classdef introduction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CitationsButton      matlab.ui.control.Button
        hack                 matlab.ui.control.Image
        SCADASecurityButton  matlab.ui.control.Button
        diagram              matlab.ui.control.Image
        TextArea             matlab.ui.control.TextArea
        psh                  matlab.ui.control.Image
        WhatisClosedLoopPumpedStorageHydropowerButton  matlab.ui.control.Button
        WhatisSCADAButton    matlab.ui.control.Button
        HomeButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            
            %return to homepage
            Homepage
            app.delete

        end

        % Button pushed function: 
        % WhatisClosedLoopPumpedStorageHydropowerButton
        function WhatisClosedLoopPumpedStorageHydropowerButtonPushed(app, event)
            
            %Displays the correct picture
            app.diagram.Visible = "off";
            app.psh.Visible = "on";
            app.hack.Visible = "off"

            %Displays the text
            app.TextArea.Value = "Pumped storage hydropower (PSH) is a type of hydroelectric energy storage. " + ...
                "It is a configuration of two water reservoirs at different elevations " + ...
                "that can generate power as water moves down from one to the other (discharge)," + ...
                " passing through a turbine. The system also requires power " + ...
                "as it pumps water back into the upper reservoir (recharge)." + ...
                " PSH acts similarly to a giant battery," + ...
                " because it can store power and then release it when needed."


        end

        % Button pushed function: WhatisSCADAButton
        function WhatisSCADAButtonPushed(app, event)
            
            %Displays the correct image
            app.diagram.Visible = "on";
            app.psh.Visible = "off";
            app.hack.Visible = "off"
            
            %Displays the text
            app.TextArea.Value = "Supervisory Control and Data Acquisition (SCADA) " + ...
            "systems are used for controlling, monitoring, and analyzing industrial devices and processes."+ ... 
            "The system consists of both software and hardware components and enables " + ...
            "remote and on-site gathering of data from the industrial equipment " + ...
            "allowing remote management of hydroelectric plants." +...
            "A SCADA system is controlled by engineers using Human Machine Interfaces (HMIs), " + ...
            "which is a software that is used to send commands to be sent to the " + ...
            "Programable Logic Controllers (PLCs) or Remote Terminal Units (RTUs)."+...
            "These are computers that directly control the turbines and " + ...
            "send back important data such as the flow speed of water that " + ...
            "is used to maintain the system and detect problems."

        end

        % Callback function: not associated with a component
        function SCADASecurityButtonPushed(app, event)
     
        end

        % Button pushed function: SCADASecurityButton
        function SCADASecurityButtonPushed2(app, event)
            %Displays the correct image
            app.diagram.Visible = "off";
            app.psh.Visible = "off";
            app.hack.Visible = "on"
            
            %Display the text
            app.TextArea.Value = "Unfortunately, SCADA systems are susceptible to a myriad of cyberattacks. " + ...
                "The local network that facilitates communication between operators and PLCs can be the target of Man-in-the-Middle (MitM) attacks. " + ...
                "Where an attacker intercepts the communications into and out of the PLCs. " + ...
                "Hypothetically, this could lead to a situation where an attacker intercepts commands closing down a turbine" + ...
                " and instead sends a command increasing the flow into it." + ...
                " Furthermore, they could silence the alarm on the system and send fake data to the HMI making it seem like everything is fine." + ...
                " Another vulnerability for SCADA systems is malware. " + ...
                "A key example of this is the Stuxnet worm which made its way into the PLCs in SCADA systems that ran uranium enrichment centrifuges in Iran. " + ...
                "Stuxnet triggered drastic changes to the centrifuge speeds destroying them over time and disguising " + ...
                "its actions by sending fake data showing that the centrifuge was spinning normally to the HMI. " + ...
                "The best way to protect a PSH plant is to airgap (no connection to the internet) the facility, " + ...
                "implement strict access control, use malware detection, and follow DOE best practices. "
        end

        % Button pushed function: CitationsButton
        function CitationsButtonPushed(app, event)
            app.TextArea.Value ="Image Credits:" +...
"https://www.gevernova.com/hydropower/" +...
"https://etap.com/packages/electrical-scada" +...
"https://securuscomms.co.uk/anatomy-of-a-cyber-attack/" +...
"https://www.pnnl.gov/hydropower-cybersecurity-and-digitalization" +...
"Information Citations:" +...
"https://www.energy.gov/eere/water/hydropower-basics" +...
"https://scada-international.com/what-is-scada/" 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.WhatisSCADAButton = uibutton(app.UIFigure, 'push');
            app.WhatisSCADAButton.ButtonPushedFcn = createCallbackFcn(app, @WhatisSCADAButtonPushed, true);
            app.WhatisSCADAButton.Text = 'What is SCADA?';
            app.WhatisClosedLoopPumpedStorageHydropowerButton = uibutton(app.UIFigure, 'push');
            app.WhatisClosedLoopPumpedStorageHydropowerButton.ButtonPushedFcn = createCallbackFcn(app, @WhatisClosedLoopPumpedStorageHydropowerButtonPushed, true);
            app.WhatisClosedLoopPumpedStorageHydropowerButton.Text = 'What is Closed-Loop Pumped Storage Hydropower?';
            app.psh = uiimage(app.UIFigure);
            app.psh.Visible = 'off';
            app.psh.ImageSource = fullfile(pathToMLAPP, 'pshgif.gif');
            app.TextArea = uitextarea(app.UIFigure);
            app.diagram = uiimage(app.UIFigure);
            app.diagram.Visible = 'off';
            app.diagram.ImageSource = fullfile(pathToMLAPP, 'diagram.png');
            app.SCADASecurityButton = uibutton(app.UIFigure, 'push');
            app.SCADASecurityButton.ButtonPushedFcn = createCallbackFcn(app, @SCADASecurityButtonPushed2, true);
            app.SCADASecurityButton.Text = 'SCADA Security';
            app.hack = uiimage(app.UIFigure);
            app.hack.Visible = 'off';
            app.hack.ImageSource = fullfile(pathToMLAPP, 'hack.jpg');
            app.CitationsButton = uibutton(app.UIFigure, 'push');
            app.CitationsButton.ButtonPushedFcn = createCallbackFcn(app, @CitationsButtonPushed, true);
            app.CitationsButton.Text = 'Citations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = introduction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end